classdef Kartik_Project04_SEDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        mmLabel_4                      matlab.ui.control.Label
        mm2Label                       matlab.ui.control.Label
        mmLabel_3                      matlab.ui.control.Label
        mmLabel_2                      matlab.ui.control.Label
        mmLabel                        matlab.ui.control.Label
        Nmm2Label_2                    matlab.ui.control.Label
        Nmm2Label                      matlab.ui.control.Label
        PMInteractionCurveButton       matlab.ui.control.StateButton
        WidthofsectionEditField        matlab.ui.control.EditField
        WidthofsectionEditFieldLabel   matlab.ui.control.Label
        DiaofBarsProvidedEditField     matlab.ui.control.EditField
        DiaofBarsProvidedEditFieldLabel  matlab.ui.control.Label
        AreaofSteelEditField           matlab.ui.control.EditField
        PlotCurveButton                matlab.ui.control.Button
        AreaofSteelEditFieldLabel      matlab.ui.control.Label
        DepthofsectionEditField        matlab.ui.control.EditField
        DepthofsectionEditFieldLabel   matlab.ui.control.Label
        GradeofsteelEditField          matlab.ui.control.EditField
        GradeofsteelEditFieldLabel     matlab.ui.control.Label
        ClearCoverEditField            matlab.ui.control.EditField
        GradeofConcreteEditField       matlab.ui.control.EditField
        GradeofConcreteEditFieldLabel  matlab.ui.control.Label
        ClearCoverEditFieldLabel       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotCurveButton
        function PlotCurveButtonPushed(app, event)
            fck2=app.GradeofConcreteEditField.Value;
            fy2=app.GradeofsteelEditField.Value;
            ast2=app.AreaofSteelEditField.Value;
            d2=app.ClearCoverEditField.Value;
            D2=app.DepthofsectionEditField.Value;
            dia2=app.DiaofBarsProvidedEditField.Value;
            B2=app.WidthofsectionEditField.Value;
            fck=str2double(fck2);
            fy=str2double(fy2);
            ast=str2double(ast2);
            d3=str2double(d2);
            D=str2double(D2);
            dia=str2double(dia2);
            B=str2double(B2);
p=(ast/(B*D))*100;
fy = 0.87 *fy;
d1 = (d3 + dia/2);
d = D-d1; % Depth of bottom reinforcement from top of the section in mm
d2 = d1/D;
E = 2 * 10^5; % Modulas of elasticity of steel in N/mm^2
a = p/fck;
xaxis = zeros(110,1); % x axis = Mu/fckb(d^2)
yaxis = zeros(110,1); % y axis = Pu/fckbd

k=4;
ec = 0.002;
es1 = ec;
ec1 = ec;
es2 = ec;
ec2 = ec;
fc1 = stress_in_concrete(ec1,fck);
fc2 = stress_in_concrete(ec2,fck);
fs1 = stress_in_steel(es1,fy);
fs2 = stress_in_steel(es2,fy);

yaxis(1,1) = 0.446 + (a/(2*100))*(fs1-fc1) + (a/(2*100))*(fs2-fc2);


for i = 1 : 1 : 20

k = 4 - i * 0.14;
emin = (0.0035 * (k-1))/( (0.75*(k-1)) + k);
ec = 0.0035 - (0.75 * emin);
es1 = emin * (k - d2) / (k-1);
ec1 = es1;
es2 = emin * (k + d2 - 1) / (k-1);
ec2 = es2;
fc1 = stress_in_concrete(ec1,fck);
fc2 = stress_in_concrete(ec2,fck);
fs1 = stress_in_steel(es1,fy);
fs2 = stress_in_steel(es2,fy);
C1 = 0.446 * (1 - ((4/21) * (4/(7*k-3))^2) );
C2 = ((1/2) - ( (8/49) * ( 4 / (7*k-3))^2 ) )/...
    (1 - ( (4/21) * (4/ (7*k-3))^2 ));
yaxis(i+1,1) = C1 + (a/(2*100))*(fs1-fc1) +...
                  (a/(2*100))*(fs2-fc2);
xaxis (i+1,1) =  C1 * (0.5 - C2) +...
                + (a/(2*100))*(fs1-fc1) * (0.5 - d2)...
                - (a/(2*100))*(fs2-fc2) * (0.5 - d2);

end
k = 1.1;
emin = (0.0035 * (k-1))/( (0.75*(k-1)) + k);
ec = 0.0035 - (0.75 * emin);
es1 = emin * (k - d2) / (k-1);
ec1 = es1;
es2 = emin * (k + d2 - 1) / (k-1);
ec2 = es2;
fc1 = stress_in_concrete(ec1,fck);
fc2 = stress_in_concrete(ec2,fck);
fs1 = stress_in_steel(es1,fy);
fs2 = stress_in_steel(es2,fy);
C1 = 0.446 * (1 - ((4/21) * (4/(7*k-3))^2) );
C2 = ((1/2) - ( (8/49) * ( 4 / (7*k-3))^2 ) )/...
    (1 - ( (4/21) * (4/ (7*k-3))^2 ));
yaxis(22,1) = C1 + (a/(2*100))*(fs1-fc1) +...
                  (a/(2*100))*(fs2-fc2);
xaxis (22,1) =  C1 * (0.5 - C2) +...
                + (a/(2*100))*(fs1-fc1) * (0.5 - d2)...
                - (a/(2*100))*(fs2-fc2) * (0.5 - d2);
for i = 1:1:21
es2 = (i-1) * .05 * fy/E;
ec = 0.0035;
k = (ec * (1 - d2) ) / (ec + es2);
es1 = ec * (k - d2) / k;
ec1 = es1;
ec2 = 0;
fc1 = stress_in_concrete(ec1,fck);
fc2 = stress_in_concrete(ec2,fck);
fs1 = stress_in_steel(es1,fy);
if (es2 * E < fy)
    fs2 = es2 * E;
else 
    fs2 = fy;
end
yaxis(i+22,1) = 0.36 * k + (a/(2*100))*(fs1-fc1) -...
                  (a/(2*100))*(fs2-fc2);
xaxis (i+22,1) =  0.36 * k * (0.5 - 0.42 * k) +...
                + (a/(2*100))*(fs1-fc1) * (0.5 - d2)...
                + (a/(2*100))*(fs2-fc2) * (0.5 - d2);
end
for i=1:1:20
es2 = fy/E + 0.0001*i;
ec = 0.0035;
k = (ec * (1 - d2) ) / (ec + es2);
es1 = ec * (k - d2) / k;
ec1 = es1;
ec2 = 0;
fc1 = stress_in_concrete(ec1,fck);
fc2 = stress_in_concrete(ec2,fck);
fs1 = stress_in_steel(es1,fy);
fs2 = fy;
yaxis(43+i,1) = 0.36 * k + (a/(2*100))*(fs1-fc1) -...
                  (a/(2*100))*(fs2-fc2);
xaxis (43+i,1) =  0.36 * k * (0.5 - 0.42 * k) +...
                + (a/(2*100))*(fs1-fc1) * (0.5 - d2)...
                + (a/(2*100))*(fs2-fc2) * (0.5 - d2);
end
k1 = linspace (k,d2,20);
k1 = k1';
for i=1:1:20
es2 = fy/E + 0.0002;
ec = 0.0035;
k = k1(i,1);
es1 = ec * (k - d2) / k;
ec1 = es1;
ec2 = 0;
fc1 = stress_in_concrete(ec1,fck);
fc2 = stress_in_concrete(ec2,fck);
fs1 = stress_in_steel(es1,fy);
fs2 = fy;
yaxis(63+i,1) = 0.36 * k + (a/(2*100))*(fs1-fc1) -...
                  (a/(2*100))*(fs2-fc2);
if (yaxis(63+i,1)<0)
    yaxis(63+i,1)=0;
    i = i-1;    
    break
end
xaxis (63+i,1) =  0.36 * k * (0.5 - 0.42 * k) +...
                + (a/(2*100))*(fs1-fc1) * (0.5 - d2)...
                + (a/(2*100))*(fs2-fc2) * (0.5 - d2);
end

xaxis (63+i+1,1) = (a/(2*100))*(fy) * (0.5 - d2)...
                + (a/(2*100))*(fy) * (0.5 - d2);
index = [1:63+i+1];
plot(app.UIAxes,xaxis(index,1),yaxis(index,1));
grid (app.UIAxes,"minor");
xlabel(app.UIAxes,'Mu/fckbd^2');
ylabel(app.UIAxes,'Pu/fckbd');
title(app.UIAxes,'PM Interaction Curve for d''/D' );


function [stress] = stress_in_steel(x,y)
E = 2 * 10^5;
strain = x;
fy = y;
  
strain1 = round(0.80 * fy / E,3);
strain6 = round(fy / E + 0.002,3);

if (fy==(0.87*415))
    strain2=0.00163; strain3=0.00192;
    strain4=0.00241; strain5=0.00276;
elseif (fy==(0.87*500))
    strain2=0.00195; strain3=0.00226;
    strain4=0.00277; strain5=0.00312;
end

if(fy==0.87*250)
    stress=strain*E; return
elseif(fy==0.87*250 & stress>(0.87*250))
        stress=0.87*250
        return
end
if (strain < strain1)
    stress = strain * E;
elseif (strain >= strain1 & strain < strain2)
    stress = 0.80 * fy + (0.85 * fy-0.80 * fy)*...
        (strain - strain1)/(strain2 - strain1);
elseif (strain >= strain2 & strain < strain3)
    stress = 0.85 * fy + (0.90 * fy-0.85 * fy)*...
        (strain - strain2)/(strain3 - strain2);
elseif (strain >= strain3 & strain < strain4)
    stress = 0.90 * fy + (0.95 * fy-0.90 * fy)*...
        (strain - strain3)/(strain4 - strain3);
elseif (strain >= strain4 & strain < strain5)
    stress = 0.95 * fy + (0.975 * fy-0.95 * fy)*...
        (strain - strain4)/(strain5 - strain4);    
elseif (strain >= strain5 & strain < strain6)
    stress = 0.975 * fy + (1 * fy-0.975 * fy)*...
        (strain - strain5)/(strain6 - strain5);
elseif (strain > strain6)
    stress = 1 * fy;
end
end
function [stress] = stress_in_concrete(x,y)
strain = x;
fck = y;
if (strain >= 0.002 & strain <= 0.0035 )
    stress = 0.446 * fck;
elseif (strain >= 0 & strain < 0.002 )
    stress = 0.446 * fck * (2*strain/0.002 - (strain/0.002)^2);
end
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ClearCoverEditFieldLabel = uilabel(app.UIFigure);
            app.ClearCoverEditFieldLabel.Text = 'Clear Cover';
            app.GradeofConcreteEditFieldLabel = uilabel(app.UIFigure);
            app.GradeofConcreteEditFieldLabel.Text = 'Grade of Concrete';
            app.GradeofConcreteEditField = uieditfield(app.UIFigure, 'text');
            app.ClearCoverEditField = uieditfield(app.UIFigure, 'text');
            app.GradeofsteelEditFieldLabel = uilabel(app.UIFigure);
            app.GradeofsteelEditFieldLabel.Text = 'Grade of steel';
            app.GradeofsteelEditField = uieditfield(app.UIFigure, 'text');
            app.DepthofsectionEditFieldLabel = uilabel(app.UIFigure);
            app.DepthofsectionEditFieldLabel.Text = 'Depth of section';
            app.DepthofsectionEditField = uieditfield(app.UIFigure, 'text');
            app.AreaofSteelEditFieldLabel = uilabel(app.UIFigure);
            app.AreaofSteelEditFieldLabel.Text = 'Area of Steel';
            app.PlotCurveButton = uibutton(app.UIFigure, 'push');
            app.PlotCurveButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurveButtonPushed, true);
            app.PlotCurveButton.Text = 'Plot Curve';
            app.AreaofSteelEditField = uieditfield(app.UIFigure, 'text');
            app.DiaofBarsProvidedEditFieldLabel = uilabel(app.UIFigure);
            app.DiaofBarsProvidedEditFieldLabel.Text = 'Dia of Bars Provided';
            app.DiaofBarsProvidedEditField = uieditfield(app.UIFigure, 'text');
            app.WidthofsectionEditFieldLabel = uilabel(app.UIFigure);
            app.WidthofsectionEditFieldLabel.Text = 'Width of section';
            app.WidthofsectionEditField = uieditfield(app.UIFigure, 'text');
            app.PMInteractionCurveButton = uibutton(app.UIFigure, 'state');
            app.PMInteractionCurveButton.Text = 'P-M Interaction Curve ';
            app.Nmm2Label = uilabel(app.UIFigure);
            app.Nmm2Label.Text = 'N/mm^2';
            app.Nmm2Label_2 = uilabel(app.UIFigure);
            app.Nmm2Label_2.Text = 'N/mm^2';
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.UIFigure);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.UIFigure);
            app.mmLabel_3.Text = 'mm';
            app.mm2Label = uilabel(app.UIFigure);
            app.mm2Label.Text = 'mm^2';
            app.mmLabel_4 = uilabel(app.UIFigure);
            app.mmLabel_4.Text = 'mm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kartik_Project04_SEDS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end